classdef FinApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        PlotGraphButton             matlab.ui.control.Button
        GreeksButtonGroup           matlab.ui.container.ButtonGroup
        LambdaButton                matlab.ui.control.RadioButton
        VegaButton                  matlab.ui.control.RadioButton
        RhoButton                   matlab.ui.control.RadioButton
        ThetaButton                 matlab.ui.control.RadioButton
        GammaButton                 matlab.ui.control.RadioButton
        DeltaButton                 matlab.ui.control.RadioButton
        VolatilityEditField         matlab.ui.control.NumericEditField
        VolatilityEditFieldLabel    matlab.ui.control.Label
        RiskfreerateEditField       matlab.ui.control.NumericEditField
        RiskfreerateEditFieldLabel  matlab.ui.control.Label
        StrikePriceEditField        matlab.ui.control.NumericEditField
        StrikePriceEditFieldLabel   matlab.ui.control.Label
        Switch                      matlab.ui.control.Switch
        TimeinMonthsPanel           matlab.ui.container.Panel
        MaxSpinner_2                matlab.ui.control.Spinner
        MaxSpinner_2Label           matlab.ui.control.Label
        IntervalSpinner_2           matlab.ui.control.Spinner
        IntervalSpinner_2Label      matlab.ui.control.Label
        MinSpinner_2                matlab.ui.control.Spinner
        MinSpinner_2Label           matlab.ui.control.Label
        StockPricePanel             matlab.ui.container.Panel
        IntervalSpinner             matlab.ui.control.Spinner
        IntervalSpinnerLabel        matlab.ui.control.Label
        MinSpinner                  matlab.ui.control.Spinner
        MinSpinnerLabel             matlab.ui.control.Label
        MaxSpinner                  matlab.ui.control.Spinner
        MaxSpinnerLabel             matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotGraphButton
        function PlotGraphButtonPushed(app, event)
            Greek = app.GreeksButtonGroup.SelectedObject.Text;
            C_or_P = app.Switch.Value;
            X = app.StrikePriceEditField.Value;
            R = app.RiskfreerateEditField.Value;
            Vol = app.VolatilityEditField.Value;
            % Range of Stock Price
            prices = app.MinSpinner.Value: app.IntervalSpinner.Value: app.MaxSpinner.Value;   
            % Scalar value, span=100 by default
            span = length(prices);            
            
            time = app.MinSpinner_2.Value: app.IntervalSpinner_2.Value: app.MaxSpinner_2.Value;                % Half-Month per stime period
            New0 = time(ones(span,1),:)'/12;  % Transposed, /12
            % New0 is the floor of (time,time,.....100times) for 100 stock prices
            
            time_ones=ones(length(time),1);
            New1 = prices(time_ones,:);
            % New1 is the floor of (prices;prices;....24times) for 24 time periods
            
            floor = ones(size(New0));
            % floor is the ones matrix of New0 or New1 dimensions
            
            
            switch Greek
                case 'Delta'
                    [Call_delta,Put_delta] = blsdelta(New1, X*floor, R*floor, New0, Vol*floor);
                    switch C_or_P
                        case 'Call'
                            Z = Call_delta;
                            name = 'Call Delta';
                        case 'Put'
                            Z = Put_delta;
                            name = 'Put Delta';
                    end
                    plot1();
                case 'Gamma'
                      Call_Put_gamma = blsgamma(New1, X*floor, R*floor, New0, Vol*floor);
                        Z = Call_Put_gamma;
                        name = 'Call / Put Gamma';
                        plot1();
                case 'Theta'
                    [Call_theta,Put_theta] = blstheta(New1, X*floor, R*floor, New0, Vol*floor);
                    switch C_or_P
                        case 'Call'
                            Z = Call_theta;
                            name = 'Call Theta';
                        case 'Put'
                            Z = Put_theta;
                            name = 'Put Theta';
                    end
                    plot1();
                case 'Rho'
                    [Call_rho,Put_rho] = blsrho(New1, X*floor, R*floor, New0, Vol*floor);
                    switch C_or_P
                        case 'Call'
                            Z = Call_rho;
                            name = 'Call Rho';
                        case 'Put'
                            Z = Put_rho;
                            name = 'Put Rho';
                    end
                    plot1();
                case 'Vega'
                    Call_Put_vega = blsvega(New1, X*floor, R*floor, New0, Vol*floor);
                        Z = Call_Put_vega;
                        name = 'Call / Put Vega';
                        plot1();
                case 'Lambda'
                    [Call_lambda,Put_lambda] = blslambda(New1, X*floor, R*floor, New0, Vol*floor);
                    switch C_or_P
                        case 'Call'
                            Z = Call_lambda;
                            name = 'Call Lambda';
                        case 'Put'
                            Z = Put_lambda;
                            name = 'Put Lambda';
                    end
                    plot1();
                otherwise 
            end
            
            function plot1()
                app.UIAxes.reset;
                mesh(app.UIAxes,prices,time,Z,X*floor) % UIAxes is the name of your axes object in app designer. Change it according to your app
                axis(app.UIAxes, 'tight');
                app.UIAxes.GridColor = [0.42,0.00,0.00];
                app.UIAxes.View = [-40,50];
                zlabel(app.UIAxes,name);
                xlabel(app.UIAxes,'Stock Price');
                ylabel(app.UIAxes,'Time in Months');
                hold(app.UIAxes);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Option Sensitivity Plot')
            xlabel(app.UIAxes, 'Stock Price')
            ylabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Greek')
            app.StockPricePanel = uipanel(app.UIFigure);
            app.StockPricePanel.Title = 'Stock Price';
            app.MaxSpinnerLabel = uilabel(app.StockPricePanel);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.StockPricePanel);
            app.MaxSpinner.Value = 100;
            app.MinSpinnerLabel = uilabel(app.StockPricePanel);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.StockPricePanel);
            app.MinSpinner.Value = 1;
            app.IntervalSpinnerLabel = uilabel(app.StockPricePanel);
            app.IntervalSpinnerLabel.Text = 'Interval';
            app.IntervalSpinner = uispinner(app.StockPricePanel);
            app.IntervalSpinner.Value = 1;
            app.TimeinMonthsPanel = uipanel(app.UIFigure);
            app.TimeinMonthsPanel.Title = 'Time in Months';
            app.MinSpinner_2Label = uilabel(app.TimeinMonthsPanel);
            app.MinSpinner_2Label.Text = 'Min';
            app.MinSpinner_2 = uispinner(app.TimeinMonthsPanel);
            app.MinSpinner_2.Value = 0.5;
            app.IntervalSpinner_2Label = uilabel(app.TimeinMonthsPanel);
            app.IntervalSpinner_2Label.Text = 'Interval';
            app.IntervalSpinner_2 = uispinner(app.TimeinMonthsPanel);
            app.IntervalSpinner_2.Value = 0.5;
            app.MaxSpinner_2Label = uilabel(app.TimeinMonthsPanel);
            app.MaxSpinner_2Label.Text = 'Max';
            app.MaxSpinner_2 = uispinner(app.TimeinMonthsPanel);
            app.MaxSpinner_2.Value = 12;
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Call', 'Put'};
            app.Switch.Value = 'Call';
            app.StrikePriceEditFieldLabel = uilabel(app.UIFigure);
            app.StrikePriceEditFieldLabel.Text = 'Strike Price';
            app.StrikePriceEditField = uieditfield(app.UIFigure, 'numeric');
            app.StrikePriceEditField.Value = 40;
            app.RiskfreerateEditFieldLabel = uilabel(app.UIFigure);
            app.RiskfreerateEditFieldLabel.Text = 'Risk free rate';
            app.RiskfreerateEditField = uieditfield(app.UIFigure, 'numeric');
            app.RiskfreerateEditField.Value = 0.1;
            app.VolatilityEditFieldLabel = uilabel(app.UIFigure);
            app.VolatilityEditFieldLabel.Text = 'Volatility';
            app.VolatilityEditField = uieditfield(app.UIFigure, 'numeric');
            app.VolatilityEditField.Value = 0.35;
            app.GreeksButtonGroup = uibuttongroup(app.UIFigure);
            app.GreeksButtonGroup.Title = 'Greeks';
            app.DeltaButton = uiradiobutton(app.GreeksButtonGroup);
            app.DeltaButton.Text = 'Delta';
            app.DeltaButton.Value = true;
            app.GammaButton = uiradiobutton(app.GreeksButtonGroup);
            app.GammaButton.Text = 'Gamma';
            app.ThetaButton = uiradiobutton(app.GreeksButtonGroup);
            app.ThetaButton.Text = 'Theta';
            app.RhoButton = uiradiobutton(app.GreeksButtonGroup);
            app.RhoButton.Text = 'Rho';
            app.VegaButton = uiradiobutton(app.GreeksButtonGroup);
            app.VegaButton.Text = 'Vega';
            app.LambdaButton = uiradiobutton(app.GreeksButtonGroup);
            app.LambdaButton.Text = 'Lambda';
            app.PlotGraphButton = uibutton(app.UIFigure, 'push');
            app.PlotGraphButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGraphButtonPushed, true);
            app.PlotGraphButton.Text = 'Plot Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end